classdef InteraccionLuzMateria < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        InteraccionLuzMateriaGUI    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        IncidenciadeLuzdesdeelMEDIO1haciaelMEDIO2Label  matlab.ui.control.Label
        FrecuenciaFinal_F           matlab.ui.control.NumericEditField
        FrecFinalLabel              matlab.ui.control.Label
        FrecuenciaInicial_F         matlab.ui.control.NumericEditField
        FrecInicialLabel            matlab.ui.control.Label
        ESPECTROdeFrecuencias       matlab.ui.control.Label
        AnguloFijado                matlab.ui.control.NumericEditField
        nguloFijoLabel              matlab.ui.control.Label
        Fijando                     matlab.ui.container.ButtonGroup
        FrecuenciaCheck             matlab.ui.control.RadioButton
        AnguloCheck                 matlab.ui.control.RadioButton
        MATERIAL                    matlab.ui.control.DropDown
        MATERIALLabel               matlab.ui.control.Label
        CalcularComparacion         matlab.ui.control.Button
        Label4                      matlab.ui.control.Label
        LambdaFinal                 matlab.ui.control.NumericEditField
        LambdaFinalumLabel          matlab.ui.control.Label
        LambdaInicial               matlab.ui.control.NumericEditField
        LambdaInicialumLabel        matlab.ui.control.Label
        CalcularFresnel             matlab.ui.control.Button
        AnguloFinal                 matlab.ui.control.NumericEditField
        nguloFinalEditFieldLabel    matlab.ui.control.Label
        AnguloInicial               matlab.ui.control.NumericEditField
        nguloInicialEditFieldLabel  matlab.ui.control.Label
        Lambda                      matlab.ui.control.NumericEditField
        LambdaumLabel               matlab.ui.control.Label
        MEDIO2                      matlab.ui.control.DropDown
        MEDIO2DropDownLabel         matlab.ui.control.Label
        MEDIO1                      matlab.ui.control.DropDown
        MEDIO1Label                 matlab.ui.control.Label
        AngulodeIncidencia          matlab.ui.control.Label
        RightPanel                  matlab.ui.container.Panel
        ElecciondeGrafica           matlab.ui.control.DropDown
        GrficaDropDownLabel         matlab.ui.control.Label
        Grafico                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.InteraccionLuzMateriaGUI.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {682, 682};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {199, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: CalcularFresnel
        function CalcularFresnelButtonPushed(app, event)
            control = app.AnguloCheck.Value;
            switch control
                case true
                    % Varación de la lambda y angulo fijo

                    lambda_inic = app.FrecuenciaInicial_F.Value;
                    lambda_final = app.FrecuenciaFinal_F.Value;

                    if lambda_inic>lambda_final
                        error('ERROR! Elije un barrido con theta_i_1 <= theta_i_2')
                    else

                        theta = app.AnguloFijado.Value;

                        material_Uno = app.MEDIO1.Value;
                        material_Dos = app.MEDIO2.Value;


                        %anadiendo la extension
                        extension = '.csv';
                        material_ext = strcat(material_Dos,extension); %concatenando los valores para onbetenr el nombre del archivo en correspondencia con material

                        %funcion que recibe el nombre del material y el rango de frecuencia inicial
                        %y frecuencia final que se quiere visualizar
                        [lambdas,n_experim,k_experim,Nt_complejo] = leer_valores_exp(material_ext,lambda_inic,lambda_final);

                        %Obtengo cuantas lambdas se van a usar en el barrido
                        n_lambdas = numel(lambdas);

                        %Creo los vectores con la cantidad de valores de barrido
                        r_per= zeros(1,n_lambdas);
                        t_per =zeros(1,n_lambdas);
                        r_par =zeros(1,n_lambdas);
                        t_par =zeros(1,n_lambdas);
                        R_per= zeros(1,n_lambdas);
                        T_per =zeros(1,n_lambdas);
                        R_par =zeros(1,n_lambdas);
                        T_par =zeros(1,n_lambdas);


                        ni=zeros(1,n_lambdas);
                        nt=zeros(1,n_lambdas);

                        for i=1:1:n_lambdas
                            %Medio donde viaja la luz antes de incidir en el material
                            if strcmpi(material_Uno,'PMMA')
                                [epsilon] = Drude_Lorentz(lambdas(i) , 'Diele', material_Uno);
                            else
                                [epsilon] = Drude_Lorentz(lambdas(i) , 'DL', material_Uno);
                            end
                            [n, k, ni(i)] = Val_N_Complejo (epsilon);

                            %Medio donde se trasmite la luz
                            if strcmpi(material_Dos,'PMMA')
                                [epsilon] = Drude_Lorentz(lambdas(i), 'Diele', material_Dos);
                            else
                                [epsilon] = Drude_Lorentz(lambdas(i) , 'DL', material_Dos);
                            end
                            [n, k, nt(i)] = Val_N_Complejo (epsilon);

                            theta_t = asind(ni(i).*sind(theta)./nt(i));
                            theta_t=real(theta_t);

                            %calculo de los coeficientes de Fresnel
                            [r_per(i),t_per(i),r_par(i),t_par(i)] = Coef_Fresnel(theta,theta_t,ni(i),nt(i));
                            %Calculo de los Factores de Tx y Rf de Energía
                            [R_per(i),T_per(i),R_par(i),T_par(i)] = Factores_de_R_y_T(ni(i),nt(i),theta,theta_t,r_per(i),t_per(i),r_par(i),t_par(i));

                        end

                        %Gráficas

                        cla(app.Grafico);
                        plot (app.Grafico,lambdas,r_per,'y',lambdas,t_per,'r',lambdas,r_par,'g',lambdas,t_par,'b');
                        title(app.Grafico,'Coeficientes de Fresnel para diferentes lambdas de incidencia');
                        xlabel(app.Grafico,'Longitud de onda \mum');
                        ylabel(app.Grafico,'Coeficientes de Amplitud');
                        legend(app.Grafico,'r Perpendicular','t Perpendicular','r Paralelo','t Paralelo');
                        app.Grafico.GridAlpha = true;

                        o2 = 'Factor de  Reflexión y Trasmisión de Energía';
                        opciones = app.ElecciondeGrafica.Value;

                        if strcmpi(opciones,o2)
                            cla(app.Grafico);
                            plot (app.Grafico,lambdas,R_per,'y',lambdas,R_par,'g',lambdas,T_per,'r',lambdas,T_par,'b');
                            title(app.Grafico,'Factor de Reflexión y Trasmisión de Energía Vs Lambdas');
                            legend(app.Grafico,'R Perpendicular','R Paralelo','T Perpendicular','T Paralelo');
                            app.Grafico.GridAlpha = true;
                            xlabel(app.Grafico,'Longitud de onda \mum');
                            ylabel(app.Grafico,'Factor de Reflexión y Trasmisión de Energía');
                        else
                            app.ElecciondeGrafica.Value = 'Coeficientes de Fresnel';
                        end

                    end
                    
                    
                case false
                    %Coeficientes de Fresnel
                    theta_i_1 = app.AnguloInicial.Value;
                    theta_i_2 =app.AnguloFinal.Value;
                    %comprobación de errores en relación con el barrido de ángulos de incidencia
                    if theta_i_1>theta_i_2
                        error('ERROR! Elije un barrido con theta_i_1 <= theta_i_2')
                    else

                        %Para una lambda un Barrido de angulos

                        lambda = app.Lambda.Value; %para una lambda de incidencia en um
                        material_Uno = app.MEDIO1.Value;
                        material_Dos = app.MEDIO2.Value;

                        %Medio donde viaja la luz antes de incidir en el material
                        if strcmpi(material_Uno,'PMMA')
                            [epsilon] = Drude_Lorentz(lambda , 'Diele', material_Uno);
                        else
                            [epsilon] = Drude_Lorentz(lambda , 'DL', material_Uno);
                        end
                        [n, k, ni] = Val_N_Complejo (epsilon);

                        %Medio donde se trasmite la luz
                        if strcmpi(material_Dos,'PMMA')
                            [epsilon] = Drude_Lorentz(lambda , 'Diele', material_Dos);
                        else
                            [epsilon] = Drude_Lorentz(lambda , 'DL', material_Dos);
                        end
                        [n, k, nt] = Val_N_Complejo (epsilon);

                        %trabajando con los coeficientes n complejos
                        %ni=abs(ni);
                        %nt=abs(nt);

                        cant_ang=theta_i_2 - theta_i_1 + 1  ; %determino la cantidad de ángulos de incidencia
                        %se suma uno porque el arreglo comienza
                        %en cero y los index de matlab en 1


                        %Creo los vectores con la cantidad de valores de barrido
                        r_per= zeros(1,cant_ang);
                        t_per =zeros(1,cant_ang);
                        r_par =zeros(1,cant_ang);
                        t_par =zeros(1,cant_ang);
                        R_per= zeros(1,cant_ang);
                        T_per =zeros(1,cant_ang);
                        R_par =zeros(1,cant_ang);
                        T_par =zeros(1,cant_ang);
                        theta_i = (theta_i_1:1:theta_i_2);%variación del ángulo de incidencia;

                        for i=1:1:cant_ang
                            %Calculo del angulo de trasmision (theta_t) por ley de Snell
                            theta_t = asind(ni.*sind(theta_i(i))./nt);
                            theta_t=real(theta_t);
                            %calculo de los coeficientes de Fresnel y angulo de Brewstwr y Critico
                            [r_per(i),t_per(i),r_par(i),t_par(i)] = Coef_Fresnel(theta_i(i),theta_t,ni,nt);
                            %Calculo de los Factores de Tx y Rf de Energia
                            [R_per(i),T_per(i),R_par(i),T_par(i)] = Factores_de_R_y_T(ni, nt,theta_i(i),theta_t, r_per(i),t_per(i),r_par(i),t_par(i));
                        end


                        %Graficas
                        cla(app.Grafico);
                        plot (app.Grafico,theta_i,r_per,'y',theta_i,t_per,'r',theta_i,r_par,'g',theta_i,t_par,'b');
                        title(app.Grafico,'Coeficientes de Fresnel para diferentes ángulos de incidencia');
                        xlabel(app.Grafico,'Angulos de Incidencia(grados)');
                        ylabel(app.Grafico,'Coeficientes de Amplitud');
                        legend(app.Grafico,'r Perpendicular','t Perpendicular','r Paralelo','t Paralelo')
                        app.Grafico.GridAlpha = true;
                        
                        
                        %graficando ángulo de Breswter
                        Ang_Brew = -1;
                        if strcmp(material_Dos,'Air') || strcmp(material_Dos,'N-BK7')
                            Ang_Brew=Ang_Brewster (abs(ni),abs(nt));
                            %Graficando el Angulo de Breswter
                            if theta_i_2 >= Ang_Brew
                                %x=Ang_Brew;
                                xline(app.Grafico,Ang_Brew,'LineStyle','-.','Color','k');
                                legend(app.Grafico,'r Perpendicular','t Perpendicular','r Paralelo','t Paralelo','Ang Brewster');
                            end
                        end

                        if ni>nt
                            %Calculo del ángulo crítico para que se produzca la reflexión total interna
                            Angulo_Critico = asind(real(nt/ni));
                            %Graficando el Angulo Critico
                            %x=Angulo_Critico;
                            xline(app.Grafico,Angulo_Critico,'LineStyle','-.','Color','r');
                            if Ang_Brew > 0 && Ang_Brew < Angulo_Critico
                                legend(app.Grafico,'r Perpendicular','t Perpendicular','r Paralelo','t Paralelo','Ang Brewster','Ang Crítico');
                            else
                                legend(app.Grafico,'r Perpendicular','t Perpendicular','r Paralelo','t Paralelo','Ang Crítico');
                            end
                        end                     
                        

                        o2 = 'Factor de  Reflexión y Trasmisión de Energía';
                                              
                        opciones = app.ElecciondeGrafica.Value;
                        
                        if strcmpi(opciones,o2)
                            cla(app.Grafico);
                            plot (app.Grafico,theta_i,R_per,'y',theta_i,R_par,'g',theta_i,T_per,'r',theta_i,T_par,'b');
                            title(app.Grafico,'Factor de Reflexión y Trasmisión de Energía Vs Ángulo de Incidencia');
                            legend(app.Grafico,'R Perpendicular','R Paralelo','T Perpendicular','T Paralelo');

                            xlabel(app.Grafico,'Angulos de Incidencia(grados)');
                            ylabel(app.Grafico,'Factor de Reflexión y Trasmisión de Energia');

                        else
                            app.ElecciondeGrafica.Value = 'Coeficientes de Fresnel';
                        end                       
                    end


            end
        end

        % Button pushed function: CalcularComparacion
        function CalcularComparacionButtonPushed(app, event)
            % Comparación del modelo de Drude-Lorentz con los valores de
            % Refractive Index
            app.ElecciondeGrafica.Value = 'Comparación del modelo de Drude-Lorentz con los valores de Refractive Index';

            %añadiendo la extensión
            material_Dos = app.MATERIAL.Value;
            extension = '.csv';
            material_ext = strcat(material_Dos,extension); %concatenando los valores para onbtenr el nombre del archivo en correspondencia con material
            
            %Introducir los valores de Longitud de Onda Inicial y Final para visualizar
            %en um
            Val_long_onda_inic = app.LambdaInicial.Value;
            Val_long_onda_final = app.LambdaFinal.Value;            
            
            
            %funcion que recibe el nombre del material y el rango de frecuencia inicial
            %y frecuencia final que se quiere visualizar
            [lambdas,n_experim,k_experim,Nt_complejo] = leer_valores_exp(material_ext,Val_long_onda_inic,Val_long_onda_final);
            
            %calculando los valores de n y k para el modelo de Drude-Lorentz
            if strcmpi(material_Dos,'PMMA')
                [epsilon] = Drude_Lorentz(lambdas , 'Diele', material_Dos);
            else
                [epsilon] = Drude_Lorentz(lambdas , 'DL', material_Dos);
            end
            
            [n, k] = Val_N_Complejo (epsilon);
            
            %cambio del label de decipcion de la Gráfica
            %app.ElecciondeGrafica.

            %Graficar los valores: n_experim, k_experim (experimentales), n y k calculados; para
            %comparar
            cla(app.Grafico);
            app.Grafico.GridAlpha = true;
            plot(app.Grafico,lambdas,n_experim,'LineStyle','-','Color','r');
            plot(app.Grafico,lambdas,n,'LineStyle','-.','Color','r')
            plot(app.Grafico,lambdas,k_experim,'LineStyle','-','Color','b')
            plot(app.Grafico,lambdas,k,'LineStyle','-.','Color','b')
            legend(app.Grafico,'n Experimental','n Drude-Lorentz','k Experimental','k Drude-Lorentz');
            title(app.Grafico,'Comparación del modelo de Drude-Lorentz con los valores de Refractive Index')
            xlabel(app.Grafico,'Longitud de onda \mum')
            ylabel(app.Grafico,'Índices de refracción')
        end

        % Selection changed function: Fijando
        function FijandoSelectionChanged(app, event)
              selectedButton = app.Fijando.SelectedObject;
              control = app.AnguloCheck.Value;
              
            switch control
                case true                  
                    app.AnguloInicial.Visible = 'off';                    
                    app.AnguloFinal.Visible = 'off';
                    app.AngulodeIncidencia.Visible = 'off';
                    app.Lambda.Visible = 'off';
                    app.AnguloFijado.Visible = 'on';
                    app.ESPECTROdeFrecuencias.Visible = 'on';
                    app.FrecuenciaFinal_F.Visible = 'on';
                    app.FrecuenciaInicial_F.Visible = 'on';
                case false
                    app.AnguloInicial.Visible = 'on';                    
                    app.AnguloFinal.Visible = 'on';
                    app.AngulodeIncidencia.Visible = 'on';
                    app.Lambda.Visible = 'on';
                    app.AnguloFijado.Visible = 'off';
                    app.ESPECTROdeFrecuencias.Visible = 'off';
                    app.FrecuenciaFinal_F.Visible = 'off';
                    app.FrecuenciaInicial_F.Visible = 'off';
                    
                    
            end       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.InteraccionLuzMateriaGUI = uifigure('Visible', 'off');
            app.InteraccionLuzMateriaGUI.Name = 'Interacción Luz-Materia';
            app.InteraccionLuzMateriaGUI.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.InteraccionLuzMateriaGUI);
            app.LeftPanel = uipanel(app.GridLayout);
            app.AngulodeIncidencia = uilabel(app.LeftPanel);
            app.AngulodeIncidencia.Text = 'ÁNGULOS de Incidencia';
            app.MEDIO1Label = uilabel(app.LeftPanel);
            app.MEDIO1Label.Text = 'MEDIO 1';
            app.MEDIO1 = uidropdown(app.LeftPanel);
            app.MEDIO1.Items = {'Air', 'Al', 'Ag', 'PMMA'};
            app.MEDIO1.Value = 'Air';
            app.MEDIO2DropDownLabel = uilabel(app.LeftPanel);
            app.MEDIO2DropDownLabel.Text = 'MEDIO 2';
            app.MEDIO2 = uidropdown(app.LeftPanel);
            app.MEDIO2.Items = {'Al', 'Air', 'Ag', 'PMMA'};
            app.MEDIO2.Value = 'Al';
            app.LambdaumLabel = uilabel(app.LeftPanel);
            app.LambdaumLabel.Text = 'Lambda Fija (um)';
            app.Lambda = uieditfield(app.LeftPanel, 'numeric');
            app.Lambda.Limits = [0 20];
            app.Lambda.Visible = 'off';
            app.Lambda.Value = 0.8;
            app.nguloInicialEditFieldLabel = uilabel(app.LeftPanel);
            app.nguloInicialEditFieldLabel.Text = 'Ángulo Inicial';
            app.AnguloInicial = uieditfield(app.LeftPanel, 'numeric');
            app.AnguloInicial.Limits = [0 90];
            app.AnguloInicial.Visible = 'off';
            app.nguloFinalEditFieldLabel = uilabel(app.LeftPanel);
            app.nguloFinalEditFieldLabel.Text = 'Ángulo Final';
            app.AnguloFinal = uieditfield(app.LeftPanel, 'numeric');
            app.AnguloFinal.Limits = [0 90];
            app.AnguloFinal.Visible = 'off';
            app.AnguloFinal.Value = 90;
            app.CalcularFresnel = uibutton(app.LeftPanel, 'push');
            app.CalcularFresnel.ButtonPushedFcn = createCallbackFcn(app, @CalcularFresnelButtonPushed, true);
            app.CalcularFresnel.Text = 'Calcular';
            app.LambdaInicialumLabel = uilabel(app.LeftPanel);
            app.LambdaInicialumLabel.Text = 'Lambda Inicial (um)';
            app.LambdaInicial = uieditfield(app.LeftPanel, 'numeric');
            app.LambdaInicial.Limits = [0 10];
            app.LambdaFinalumLabel = uilabel(app.LeftPanel);
            app.LambdaFinalumLabel.Text = 'Lambda Final (um)';
            app.LambdaFinal = uieditfield(app.LeftPanel, 'numeric');
            app.LambdaFinal.Limits = [0 200];
            app.Label4 = uilabel(app.LeftPanel);
            app.Label4.Text = {'Caracterización y Comparación '; 'de los valores calculados por el '; 'Modelo de Drude-Lorentz con '; 'los datos de refractiveindex.info'};
            app.CalcularComparacion = uibutton(app.LeftPanel, 'push');
            app.CalcularComparacion.ButtonPushedFcn = createCallbackFcn(app, @CalcularComparacionButtonPushed, true);
            app.CalcularComparacion.Text = 'Calcular';
            app.MATERIALLabel = uilabel(app.LeftPanel);
            app.MATERIALLabel.Text = 'MATERIAL';
            app.MATERIAL = uidropdown(app.LeftPanel);
            app.MATERIAL.Items = {'Al', 'Ag', 'PMMA'};
            app.MATERIAL.Value = 'Al';
            app.Fijando = uibuttongroup(app.LeftPanel);
            app.Fijando.SelectionChangedFcn = createCallbackFcn(app, @FijandoSelectionChanged, true);
            app.Fijando.Title = 'Fijando';
            app.AnguloCheck = uiradiobutton(app.Fijando);
            app.AnguloCheck.Text = 'Ángulo';
            app.AnguloCheck.Value = true;
            app.FrecuenciaCheck = uiradiobutton(app.Fijando);
            app.FrecuenciaCheck.Text = 'Frecuencia';
            app.nguloFijoLabel = uilabel(app.LeftPanel);
            app.nguloFijoLabel.Text = 'Ángulo Fijo';
            app.AnguloFijado = uieditfield(app.LeftPanel, 'numeric');
            app.AnguloFijado.Limits = [0 90];
            app.ESPECTROdeFrecuencias = uilabel(app.LeftPanel);
            app.ESPECTROdeFrecuencias.Text = 'ESPECTRO de Frecuencias';
            app.FrecInicialLabel = uilabel(app.LeftPanel);
            app.FrecInicialLabel.Text = 'Frec. Inicial';
            app.FrecuenciaInicial_F = uieditfield(app.LeftPanel, 'numeric');
            app.FrecuenciaInicial_F.Limits = [0 90];
            app.FrecFinalLabel = uilabel(app.LeftPanel);
            app.FrecFinalLabel.Text = 'Frec. Final';
            app.FrecuenciaFinal_F = uieditfield(app.LeftPanel, 'numeric');
            app.FrecuenciaFinal_F.Limits = [0 20];
            app.FrecuenciaFinal_F.Value = 20;
            app.IncidenciadeLuzdesdeelMEDIO1haciaelMEDIO2Label = uilabel(app.LeftPanel);
            app.IncidenciadeLuzdesdeelMEDIO1haciaelMEDIO2Label.Text = {'Incidencia de Luz desde el '; 'MEDIO 1 hacia el MEDIO 2'};
            app.RightPanel = uipanel(app.GridLayout);
            app.Grafico = uiaxes(app.RightPanel);
            title(app.Grafico, 'Title')
            xlabel(app.Grafico, 'X')
            ylabel(app.Grafico, 'Y')
            app.GrficaDropDownLabel = uilabel(app.RightPanel);
            app.GrficaDropDownLabel.Text = 'Gráfica:';
            app.ElecciondeGrafica = uidropdown(app.RightPanel);
            app.ElecciondeGrafica.Items = {'Comparación del modelo de Drude-Lorentz con los valores de Refractive Index', 'Coeficientes de Fresnel', 'Factor de  Reflexión y Trasmisión de Energía'};
            app.ElecciondeGrafica.Value = 'Comparación del modelo de Drude-Lorentz con los valores de Refractive Index';
            % Show the figure after all components are created
            app.InteraccionLuzMateriaGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InteraccionLuzMateria

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.InteraccionLuzMateriaGUI)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.InteraccionLuzMateriaGUI)
        end
    end
end